classdef roskam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        save_new                     matlab.ui.control.Button
        filename                     matlab.ui.control.Label
        load                         matlab.ui.control.Button
        save                         matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        resumen                      matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
        tipo                         matlab.ui.control.DropDown
        TipodeaeronaveDropDownLabel  matlab.ui.control.Label
        fc                           matlab.ui.container.Tab
        Tabla21Button                matlab.ui.control.Button
        W98                          matlab.ui.control.EditField
        W8Label_2                    matlab.ui.control.Label
        TabGroup2                    matlab.ui.container.TabGroup
        AscensoTab                   matlab.ui.container.Tab
        Tabla22Button1               matlab.ui.control.Button
        n_p_cl                       matlab.ui.control.EditField
        W4Label_7                    matlab.ui.control.Label
        c_cl                         matlab.ui.control.EditField
        W4Label_5                    matlab.ui.control.Label
        W4Label_4                    matlab.ui.control.Label
        V_cl                         matlab.ui.control.EditField
        LD_cl                        matlab.ui.control.EditField
        W4Label_3                    matlab.ui.control.Label
        E_cl                         matlab.ui.control.EditField
        W4Label_2                    matlab.ui.control.Label
        CruceroTab                   matlab.ui.container.Tab
        Tabla22Button2               matlab.ui.control.Button
        V_cr                         matlab.ui.control.EditField
        W4Label_12                   matlab.ui.control.Label
        LD_cr                        matlab.ui.control.EditField
        W4Label_11                   matlab.ui.control.Label
        n_p_cr                       matlab.ui.control.EditField
        W4Label_10                   matlab.ui.control.Label
        c_cr                         matlab.ui.control.EditField
        W4Label_9                    matlab.ui.control.Label
        R_cr                         matlab.ui.control.EditField
        W4Label_8                    matlab.ui.control.Label
        LoiterTab                    matlab.ui.container.Tab
        Tabla22Button3               matlab.ui.control.Button
        E_lo                         matlab.ui.control.EditField
        W4Label_17                   matlab.ui.control.Label
        V_lo                         matlab.ui.control.EditField
        W4Label_16                   matlab.ui.control.Label
        n_p_lo                       matlab.ui.control.EditField
        W4Label_15                   matlab.ui.control.Label
        c_lo                         matlab.ui.control.EditField
        W4Label_14                   matlab.ui.control.Label
        LD_lo                        matlab.ui.control.EditField
        W4Label_13                   matlab.ui.control.Label
        m_ff                         matlab.ui.control.EditField
        M_ffEditFieldLabel           matlab.ui.control.Label
        W87                          matlab.ui.control.EditField
        W8Label                      matlab.ui.control.Label
        W76                          matlab.ui.control.EditField
        W7Label                      matlab.ui.control.Label
        W65                          matlab.ui.control.EditField
        W6Label                      matlab.ui.control.Label
        W54                          matlab.ui.control.EditField
        W5Label                      matlab.ui.control.Label
        W43                          matlab.ui.control.EditField
        W4Label                      matlab.ui.control.Label
        W32                          matlab.ui.control.EditField
        W3Label                      matlab.ui.control.Label
        W21                          matlab.ui.control.EditField
        W2Label                      matlab.ui.control.Label
        W1TO                         matlab.ui.control.EditField
        W_1Label                     matlab.ui.control.Label
        emptyweight                  matlab.ui.container.Tab
        eqerror                      matlab.ui.control.Label
        WEtarget                     matlab.ui.control.EditField
        W5Label_13                   matlab.ui.control.Label
        WError                       matlab.ui.control.EditField
        W5Label_12                   matlab.ui.control.Label
        WPlotButton                  matlab.ui.control.Button
        W_to                         matlab.ui.control.EditField
        W5Label_11                   matlab.ui.control.Label
        we_eq                        matlab.ui.control.Label
        WE                           matlab.ui.control.EditField
        W5Label_10                   matlab.ui.control.Label
        Wcrew                        matlab.ui.control.EditField
        W5Label_9                    matlab.ui.control.Label
        Wpl                          matlab.ui.control.EditField
        W5Label_8                    matlab.ui.control.Label
        Wtfo                         matlab.ui.control.EditField
        W5Label_7                    matlab.ui.control.Label
        Wf_res                       matlab.ui.control.EditField
        W5Label_6                    matlab.ui.control.Label
        Wf_used                      matlab.ui.control.EditField
        W5Label_5                    matlab.ui.control.Label
        Wf                           matlab.ui.control.EditField
        W5Label_4                    matlab.ui.control.Label
        stallspeed                   matlab.ui.container.Tab
        Tabla31Button                matlab.ui.control.Button
        stalllabel                   matlab.ui.control.Label
        StallReqs                    matlab.ui.control.Table
    end

    properties (Access = private)
        table21 = [
            [0.998, 0.998, 0.998, 0.995, 0.995];
            [0.995, 0.997, 0.998, 0.993, 0.993];
            [0.992, 0.996, 0.996, 0.992, 0.992];
            [0.996, 0.995, 0.996, 0.999, 0.998];
            [0.990, 0.995, 0.995, 0.990, 0.992];
            [0.990, 0.995, 0.995, 0.985, 0.995];
            [0.990, 0.990, 0.995, 0.990, 0.992];
            [0.990, 0.990, 0.990, 0.990, 0.995];
            [0.990, 0.990, 0.990, 0.990, 0.995];
            [0.990, 0.990, 0.995, 0.990, 0.992];
            [0.992, 0.990, 0.996, 0.990, 0.990];
            [0.990, 0.995, 0.995, 0.985, 0.992]
        ];
        table29 = transpose([
            % BOEING
            [184800, 100000];
            [112500, 61630];
            [124500, 69930];
            [775000, 380000];
            [630000, 325000];
            [220000, 130420];
            [300000, 179082];
            % McDONNELL-DOUGLAS
            [325000, 162700];
            [121000, 57190];
            [140000, 79757];
            [455000, 244903];
            [555000, 271062];
            [510000, 245500];
            [73000, 38683];
            [99650, 53762];
            [44000, 26850];
            [89500, 48500];
            % AIRBUS
            [363760, 195109];
            [291000, 168910];
            [357150, 153000];
            [198416, 95900]
        ]);
        prop = true;
        file = "";
    end
    methods (Access = private)
        function Tabla22Figure(app)
            f = figure(3);
            f.MenuBar = 'none';
            f.Name = 'Tabla 2.2';
            imshow('tabla22.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Value changed function: tipo
        function tipoValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: W1TO
        function W1TOValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: W21
        function W21ValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: W32
        function W32ValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: W76
        function W76ValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: W87
        function W87ValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: E_cl
        function E_clValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: V_cl
        function V_clValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: R_cr
        function R_crValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: c_cl
        function c_clValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: n_p_cl
        function n_p_clValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: LD_cl
        function LD_clValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: V_cr
        function V_crValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: c_cr
        function c_crValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: n_p_cr
        function n_p_crValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: LD_cr
        function LD_crValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: V_lo
        function V_loValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: c_lo
        function c_loValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: n_p_lo
        function n_p_loValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: LD_lo
        function LD_loValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: E_lo
        function E_loValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: W_to
        function W_toValueChanged(app, event)
            app.updateCalculation();
        end

        % Button pushed function: save_new
        function save_newButtonPushed(app, event)
            app.saveFile(1);
        end

        % Button pushed function: load
        function loadButtonPushed(app, event)
            app.loadFile("");
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            app.saveFile(0);
        end

        % Value changed function: Wf_res
        function Wf_resValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: Wpl
        function WplValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: Wcrew
        function WcrewValueChanged(app, event)
            app.updateCalculation();
        end

        % Value changed function: W98
        function W98ValueChanged(app, event)
            app.updateCalculation();
        end

        % Button pushed function: Tabla21Button
        function Tabla21ButtonPushed(app, event)
            f = figure(1);
            f.MenuBar = 'none';
            f.Name = 'Tabla 2.1';
            imshow('tabla21.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]);
        end

        % Button pushed function: WPlotButton
        function WPlotButtonPushed(app, event)
            app.refreshWeightPlot();
            app.updateCalculation();
        end

        % Button pushed function: Tabla22Button1
        function Tabla22Button1Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla22Button2
        function Tabla22Button2Pushed(app, event)
            app.Tabla22Figure();
        end

        % Button pushed function: Tabla22Button3
        function Tabla22Button3Pushed(app, event)
            app.Tabla22Figure();
        end

        % Cell edit callback: StallReqs
        function StallReqsCellEdit(app, event)
            app.updateStallSizing();
        end

        % Button pushed function: Tabla31Button
        function Tabla31ButtonPushed(app, event)
            f = figure(4);
            f.MenuBar = 'none';
            f.Name = 'Tabla 3.1';
            imshow('tabla31.png');
            set(gca,'units','pixels');
            x = get(gca,'position');
            set(gcf,'units','pixels');
            y = get(gcf,'position');
            set(gcf,'position',[y(1) y(2) x(3) x(4)]);
            set(gca,'units','normalized','position',[0 0 1 1]); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.resumen = uitab(app.TabGroup);
            app.resumen.Title = 'Resumen';
            app.TipodeaeronaveDropDownLabel = uilabel(app.resumen);
            app.TipodeaeronaveDropDownLabel.Text = 'Tipo de aeronave';
            app.tipo = uidropdown(app.resumen);
            app.tipo.Items = {'Homebuilt Propeller Driven Airplanes', 'Single Engine Propeller Airplanes', 'Twin Engine Propeller Airplanes', 'Agricultural Airplanes', 'Business Jets', 'Regional Turbopropeller Driven Airplanes', 'Transport Jets', 'Military Trainers', 'Fighters', 'Military Patrol, Bomb and Transport Airplanes', 'Flying Boats, Amphibious and Float Airplanes', 'Supersonic Cruise Airplanes'};
            app.tipo.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.tipo.ValueChangedFcn = createCallbackFcn(app, @tipoValueChanged, true);
            app.tipo.Enable = 'off';
            app.tipo.Value = '7';
            app.Label = uilabel(app.resumen);
            app.fc = uitab(app.TabGroup);
            app.fc.Title = 'Fracción de combustible';
            app.W_1Label = uilabel(app.fc);
            app.W_1Label.Interpreter = 'latex';
            app.W_1Label.Text = '$W_{1/TO}$';
            app.W1TO = uieditfield(app.fc, 'text');
            app.W1TO.ValueChangedFcn = createCallbackFcn(app, @W1TOValueChanged, true);
            app.W2Label = uilabel(app.fc);
            app.W2Label.Interpreter = 'latex';
            app.W2Label.Text = '$W_{2/1}$';
            app.W21 = uieditfield(app.fc, 'text');
            app.W21.ValueChangedFcn = createCallbackFcn(app, @W21ValueChanged, true);
            app.W3Label = uilabel(app.fc);
            app.W3Label.Interpreter = 'latex';
            app.W3Label.Text = '$W_{3/2}$';
            app.W32 = uieditfield(app.fc, 'text');
            app.W32.ValueChangedFcn = createCallbackFcn(app, @W32ValueChanged, true);
            app.W4Label = uilabel(app.fc);
            app.W4Label.Interpreter = 'latex';
            app.W4Label.Text = '$W_{4/3}$';
            app.W43 = uieditfield(app.fc, 'text');
            app.W43.Editable = 'off';
            app.W5Label = uilabel(app.fc);
            app.W5Label.Interpreter = 'latex';
            app.W5Label.Text = '$W_{5/4}$';
            app.W54 = uieditfield(app.fc, 'text');
            app.W54.Editable = 'off';
            app.W6Label = uilabel(app.fc);
            app.W6Label.Interpreter = 'latex';
            app.W6Label.Text = '$W_{6/5}$';
            app.W65 = uieditfield(app.fc, 'text');
            app.W65.Editable = 'off';
            app.W7Label = uilabel(app.fc);
            app.W7Label.Interpreter = 'latex';
            app.W7Label.Text = '$W_{7/6}$';
            app.W76 = uieditfield(app.fc, 'text');
            app.W76.ValueChangedFcn = createCallbackFcn(app, @W76ValueChanged, true);
            app.W8Label = uilabel(app.fc);
            app.W8Label.Interpreter = 'latex';
            app.W8Label.Text = '$W_{8/7}$';
            app.W87 = uieditfield(app.fc, 'text');
            app.W87.ValueChangedFcn = createCallbackFcn(app, @W87ValueChanged, true);
            app.M_ffEditFieldLabel = uilabel(app.fc);
            app.M_ffEditFieldLabel.Interpreter = 'latex';
            app.M_ffEditFieldLabel.Text = '$M_{ff}$';
            app.m_ff = uieditfield(app.fc, 'text');
            app.m_ff.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.fc);
            app.AscensoTab = uitab(app.TabGroup2);
            app.AscensoTab.Title = 'Ascenso';
            app.W4Label_2 = uilabel(app.AscensoTab);
            app.W4Label_2.Interpreter = 'latex';
            app.W4Label_2.Text = '$E$';
            app.E_cl = uieditfield(app.AscensoTab, 'text');
            app.E_cl.ValueChangedFcn = createCallbackFcn(app, @E_clValueChanged, true);
            app.E_cl.Tooltip = {'hr'};
            app.W4Label_3 = uilabel(app.AscensoTab);
            app.W4Label_3.Interpreter = 'latex';
            app.W4Label_3.Text = '$\frac{L}{D}$';
            app.LD_cl = uieditfield(app.AscensoTab, 'text');
            app.LD_cl.ValueChangedFcn = createCallbackFcn(app, @LD_clValueChanged, true);
            app.V_cl = uieditfield(app.AscensoTab, 'text');
            app.V_cl.ValueChangedFcn = createCallbackFcn(app, @V_clValueChanged, true);
            app.V_cl.Tooltip = {''};
            app.W4Label_4 = uilabel(app.AscensoTab);
            app.W4Label_4.Interpreter = 'latex';
            app.W4Label_4.Text = '$V$';
            app.W4Label_5 = uilabel(app.AscensoTab);
            app.W4Label_5.Interpreter = 'latex';
            app.W4Label_5.Text = '$c$';
            app.c_cl = uieditfield(app.AscensoTab, 'text');
            app.c_cl.ValueChangedFcn = createCallbackFcn(app, @c_clValueChanged, true);
            app.W4Label_7 = uilabel(app.AscensoTab);
            app.W4Label_7.Interpreter = 'latex';
            app.W4Label_7.Text = '$\eta_p$';
            app.n_p_cl = uieditfield(app.AscensoTab, 'text');
            app.n_p_cl.ValueChangedFcn = createCallbackFcn(app, @n_p_clValueChanged, true);
            app.Tabla22Button1 = uibutton(app.AscensoTab, 'push');
            app.Tabla22Button1.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button1Pushed, true);
            app.Tabla22Button1.Text = 'Tabla 2.2';
            app.CruceroTab = uitab(app.TabGroup2);
            app.CruceroTab.Title = 'Crucero';
            app.W4Label_8 = uilabel(app.CruceroTab);
            app.W4Label_8.Interpreter = 'latex';
            app.W4Label_8.Text = '$R$';
            app.R_cr = uieditfield(app.CruceroTab, 'text');
            app.R_cr.ValueChangedFcn = createCallbackFcn(app, @R_crValueChanged, true);
            app.R_cr.Tooltip = {'nm'};
            app.W4Label_9 = uilabel(app.CruceroTab);
            app.W4Label_9.Interpreter = 'latex';
            app.W4Label_9.Text = '$c$';
            app.c_cr = uieditfield(app.CruceroTab, 'text');
            app.c_cr.ValueChangedFcn = createCallbackFcn(app, @c_crValueChanged, true);
            app.W4Label_10 = uilabel(app.CruceroTab);
            app.W4Label_10.Interpreter = 'latex';
            app.W4Label_10.Text = '$\eta_p$';
            app.n_p_cr = uieditfield(app.CruceroTab, 'text');
            app.n_p_cr.ValueChangedFcn = createCallbackFcn(app, @n_p_crValueChanged, true);
            app.W4Label_11 = uilabel(app.CruceroTab);
            app.W4Label_11.Interpreter = 'latex';
            app.W4Label_11.Text = '$\frac{L}{D}$';
            app.LD_cr = uieditfield(app.CruceroTab, 'text');
            app.LD_cr.ValueChangedFcn = createCallbackFcn(app, @LD_crValueChanged, true);
            app.W4Label_12 = uilabel(app.CruceroTab);
            app.W4Label_12.Interpreter = 'latex';
            app.W4Label_12.Text = '$V$';
            app.V_cr = uieditfield(app.CruceroTab, 'text');
            app.V_cr.ValueChangedFcn = createCallbackFcn(app, @V_crValueChanged, true);
            app.V_cr.Tooltip = {''};
            app.Tabla22Button2 = uibutton(app.CruceroTab, 'push');
            app.Tabla22Button2.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button2Pushed, true);
            app.Tabla22Button2.Text = 'Tabla 2.2';
            app.LoiterTab = uitab(app.TabGroup2);
            app.LoiterTab.Title = 'Loiter';
            app.W4Label_13 = uilabel(app.LoiterTab);
            app.W4Label_13.Interpreter = 'latex';
            app.W4Label_13.Text = '$\frac{L}{D}$';
            app.LD_lo = uieditfield(app.LoiterTab, 'text');
            app.LD_lo.ValueChangedFcn = createCallbackFcn(app, @LD_loValueChanged, true);
            app.W4Label_14 = uilabel(app.LoiterTab);
            app.W4Label_14.Interpreter = 'latex';
            app.W4Label_14.Text = '$c$';
            app.c_lo = uieditfield(app.LoiterTab, 'text');
            app.c_lo.ValueChangedFcn = createCallbackFcn(app, @c_loValueChanged, true);
            app.W4Label_15 = uilabel(app.LoiterTab);
            app.W4Label_15.Interpreter = 'latex';
            app.W4Label_15.Text = '$\eta_p$';
            app.n_p_lo = uieditfield(app.LoiterTab, 'text');
            app.n_p_lo.ValueChangedFcn = createCallbackFcn(app, @n_p_loValueChanged, true);
            app.W4Label_16 = uilabel(app.LoiterTab);
            app.W4Label_16.Interpreter = 'latex';
            app.W4Label_16.Text = '$V$';
            app.V_lo = uieditfield(app.LoiterTab, 'text');
            app.V_lo.ValueChangedFcn = createCallbackFcn(app, @V_loValueChanged, true);
            app.V_lo.Tooltip = {'S'};
            app.W4Label_17 = uilabel(app.LoiterTab);
            app.W4Label_17.Interpreter = 'latex';
            app.W4Label_17.Text = '$E$';
            app.E_lo = uieditfield(app.LoiterTab, 'text');
            app.E_lo.ValueChangedFcn = createCallbackFcn(app, @E_loValueChanged, true);
            app.E_lo.Tooltip = {'hr'};
            app.Tabla22Button3 = uibutton(app.LoiterTab, 'push');
            app.Tabla22Button3.ButtonPushedFcn = createCallbackFcn(app, @Tabla22Button3Pushed, true);
            app.Tabla22Button3.Text = 'Tabla 2.2';
            app.W8Label_2 = uilabel(app.fc);
            app.W8Label_2.Interpreter = 'latex';
            app.W8Label_2.Text = '$W_{9/8}$';
            app.W98 = uieditfield(app.fc, 'text');
            app.W98.ValueChangedFcn = createCallbackFcn(app, @W98ValueChanged, true);
            app.Tabla21Button = uibutton(app.fc, 'push');
            app.Tabla21Button.ButtonPushedFcn = createCallbackFcn(app, @Tabla21ButtonPushed, true);
            app.Tabla21Button.Text = 'Tabla 2.1';
            app.emptyweight = uitab(app.TabGroup);
            app.emptyweight.Title = 'Peso en vacío';
            app.W5Label_4 = uilabel(app.emptyweight);
            app.W5Label_4.Interpreter = 'latex';
            app.W5Label_4.Text = '$W_F$';
            app.Wf = uieditfield(app.emptyweight, 'text');
            app.Wf.Editable = 'off';
            app.W5Label_5 = uilabel(app.emptyweight);
            app.W5Label_5.Interpreter = 'latex';
            app.W5Label_5.Text = '$=W_{f,used}$';
            app.Wf_used = uieditfield(app.emptyweight, 'text');
            app.Wf_used.Editable = 'off';
            app.W5Label_6 = uilabel(app.emptyweight);
            app.W5Label_6.Interpreter = 'latex';
            app.W5Label_6.Text = '$+W_{f,res}$';
            app.Wf_res = uieditfield(app.emptyweight, 'text');
            app.Wf_res.ValueChangedFcn = createCallbackFcn(app, @Wf_resValueChanged, true);
            app.Wf_res.Editable = 'off';
            app.W5Label_7 = uilabel(app.emptyweight);
            app.W5Label_7.Interpreter = 'latex';
            app.W5Label_7.Text = '$W_{tfo}$';
            app.Wtfo = uieditfield(app.emptyweight, 'text');
            app.Wtfo.Editable = 'off';
            app.W5Label_8 = uilabel(app.emptyweight);
            app.W5Label_8.Interpreter = 'latex';
            app.W5Label_8.Text = '$W_{PL}$';
            app.Wpl = uieditfield(app.emptyweight, 'text');
            app.Wpl.ValueChangedFcn = createCallbackFcn(app, @WplValueChanged, true);
            app.W5Label_9 = uilabel(app.emptyweight);
            app.W5Label_9.Interpreter = 'latex';
            app.W5Label_9.Text = '$W_{crew}$';
            app.Wcrew = uieditfield(app.emptyweight, 'text');
            app.Wcrew.ValueChangedFcn = createCallbackFcn(app, @WcrewValueChanged, true);
            app.W5Label_10 = uilabel(app.emptyweight);
            app.W5Label_10.Interpreter = 'latex';
            app.W5Label_10.Text = '$W_E$';
            app.WE = uieditfield(app.emptyweight, 'text');
            app.WE.Editable = 'off';
            app.we_eq = uilabel(app.emptyweight);
            app.we_eq.Interpreter = 'latex';
            app.we_eq.Text = '$=W_{to}-W_F-W_{PL}-W_{tfo}-W_{crew}$';
            app.W5Label_11 = uilabel(app.emptyweight);
            app.W5Label_11.Interpreter = 'latex';
            app.W5Label_11.Text = '$W_{to}$';
            app.W_to = uieditfield(app.emptyweight, 'text');
            app.W_to.ValueChangedFcn = createCallbackFcn(app, @W_toValueChanged, true);
            app.W_to.Tooltip = {''};
            app.WPlotButton = uibutton(app.emptyweight, 'push');
            app.WPlotButton.ButtonPushedFcn = createCallbackFcn(app, @WPlotButtonPushed, true);
            app.WPlotButton.Text = 'Plot';
            app.W5Label_12 = uilabel(app.emptyweight);
            app.W5Label_12.Interpreter = 'latex';
            app.W5Label_12.Text = '$Err$';
            app.WError = uieditfield(app.emptyweight, 'text');
            app.WError.Editable = 'off';
            app.W5Label_13 = uilabel(app.emptyweight);
            app.W5Label_13.Interpreter = 'latex';
            app.W5Label_13.Text = '$\rightarrow W_{E, target}$';
            app.WEtarget = uieditfield(app.emptyweight, 'text');
            app.WEtarget.Editable = 'off';
            app.WEtarget.Tooltip = {''};
            app.eqerror = uilabel(app.emptyweight);
            app.eqerror.Interpreter = 'latex';
            app.eqerror.Text = '$=\frac{|W_E-W_{E,target}|}{W_{E,target}}\cdot 100$';
            app.stallspeed = uitab(app.TabGroup);
            app.stallspeed.Title = 'Stall Speed Sizing';
            app.StallReqs = uitable(app.stallspeed);
            app.StallReqs.ColumnName = {'rho'; 'Vs'; 'CLmax'; 'W/S'};
            app.StallReqs.ColumnEditable = [true true true false];
            app.StallReqs.CellEditCallback = createCallbackFcn(app, @StallReqsCellEdit, true);
            app.StallReqs.Multiselect = 'off';
            app.StallReqs.Tooltip = {'Densidad slugs/ft3'; 'Velocidad ktas'};
            app.stalllabel = uilabel(app.stallspeed);
            app.stalllabel.Text = 'Condiciones de diseño';
            app.Tabla31Button = uibutton(app.stallspeed, 'push');
            app.Tabla31Button.ButtonPushedFcn = createCallbackFcn(app, @Tabla31ButtonPushed, true);
            app.Tabla31Button.Text = 'Tabla 3.1';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = 'Guardar';
            app.load = uibutton(app.UIFigure, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.load.Text = 'Cargar';
            app.filename = uilabel(app.UIFigure);
            app.filename.Text = 'Ningún archivo cargado';
            app.save_new = uibutton(app.UIFigure, 'push');
            app.save_new.ButtonPushedFcn = createCallbackFcn(app, @save_newButtonPushed, true);
            app.save_new.Text = 'Guardar nuevo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roskam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end